classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        RestorationMethodDropDown       matlab.ui.control.DropDown
        RestorationMethodDropDownLabel  matlab.ui.control.Label
        InputFileEditField              matlab.ui.control.EditField
        InputFileEditFieldLabel         matlab.ui.control.Label
        BlueChannelCheckBox             matlab.ui.control.CheckBox
        GreenChannelCheckBox            matlab.ui.control.CheckBox
        RedChannelCheckBox              matlab.ui.control.CheckBox
        InputImage                      matlab.ui.control.Image
        PiecewiseLinearTransformInputPanel  matlab.ui.container.Panel
        PLT_min                         matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        PLT_max                         matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        InverseLogTransformInputPanel   matlab.ui.container.Panel
        kexpkc1Label                    matlab.ui.control.Label
        ILT_cEditField                  matlab.ui.control.NumericEditField
        gLabel_2                        matlab.ui.control.Label
        PowerTransformInputPanel        matlab.ui.container.Panel
        kckgLabel                       matlab.ui.control.Label
        PT_cEditField                   matlab.ui.control.NumericEditField
        cLabel                          matlab.ui.control.Label
        PT_gEditField                   matlab.ui.control.NumericEditField
        gLabel                          matlab.ui.control.Label
        ImageBrigthenInputPanel         matlab.ui.container.Panel
        kakbLabel                       matlab.ui.control.Label
        Brighten_bEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        Brighten_aEditField             matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        HistSpecInputPanel              matlab.ui.container.Panel
        SpecImage_2                     matlab.ui.control.Image
        SpecInputFileEditField          matlab.ui.control.EditField
        InputSpecificationImageLabel    matlab.ui.control.Label
        SpecHistAxes_2                  matlab.ui.control.UIAxes
        PanelTemplate                   matlab.ui.container.Panel
        OutputImage                     matlab.ui.control.UIAxes
        OutputHistAxes                  matlab.ui.control.UIAxes
        InputHistAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImage_val
        OutputImage_val
        SpecImage_val
    end
    
    methods (Access = private)
        
        function SetHistogram(app, axes, img)
            hist_data = myHist(img);
            [L, num_ch] = size(hist_data);
            color_values = 0:(L - 1);
            cla(axes)
            hold(axes, "on");
            for c = 1:num_ch
                if IsChannelDisplayed(app, c)
                    color_name = GetBarColor(app, c);
                    bar(axes, color_values, hist_data(:, c), color_name, color_name);
                end 
            end
            hold(axes, "off");
        end
        
        function color_name = GetBarColor(~, idx)
            if idx == 1
                color_name = 'red';
            elseif idx == 2
                color_name = 'green';
            else
                color_name = 'blue';
            end
        end
        
        function displayed = IsChannelDisplayed(app, idx)
            if idx == 1
                displayed = app.RedChannelCheckBox.Value;
            elseif idx == 2
                displayed = app.GreenChannelCheckBox.Value;
            else
                displayed = app.BlueChannelCheckBox.Value;
            end
        end
        
        function ChangeWindowWidth(app, newWidth)
            app.UIFigure.Position = [100 100 newWidth 640];
        end
        
        function ResetFigure(app)
            app.ImageBrigthenInputPanel.Visible = "off";
            app.HistSpecInputPanel.Visible = 'off';
            app.PowerTransformInputPanel.Visible = "off";
            app.InverseLogTransformInputPanel.Visible = "off";
            app.PiecewiseLinearTransformInputPanel.Visible = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputFileEditField
        function InputFileEditFieldValueChanged(app, event)
            value = app.InputFileEditField.Value;
            app.InputImage.ImageSource = value;
            app.InputImage_val = imread(value);
            SetHistogram(app, app.InputHistAxes, app.InputImage_val);
        end

        % Value changed function: BlueChannelCheckBox, 
        % ...and 2 other components
        function ResetHistogram(app, event)
            SetHistogram(app, app.InputHistAxes, app.InputImage_val);
            SetHistogram(app, app.OutputHistAxes, app.OutputImage_val);
        end

        % Value changed function: RestorationMethodDropDown
        function RestorationMethodDropDownValueChanged(app, event)
            value = app.RestorationMethodDropDown.Value;
            ResetFigure(app);
            if strcmp('Image Brightening', value)
                app.ImageBrigthenInputPanel.Visible = "on";
            elseif strcmp('Power Transform', value)
                app.PowerTransformInputPanel.Visible = "on";
            elseif strcmp('Piecewise Linear Transform', value)
                app.PiecewiseLinearTransformInputPanel.Visible = "on";
            elseif strcmp('Inverse Log Transform', value)
                app.InverseLogTransformInputPanel.Visible = "on";
            elseif strcmp('Histogram Specification', value)
                app.HistSpecInputPanel.Visible = "on";
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            method = app.RestorationMethodDropDown.Value;
            if strcmp('Image Brightening', method)
                app.OutputImage_val = imgBrighten(app.InputImage_val, app.Brighten_bEditField.Value, app.Brighten_aEditField.Value);
            elseif strcmp('Contrast Stretching', method)
                app.OutputImage_val = contrastStretch(app.InputImage_val);
            elseif strcmp ('Power Transform', method)
                app.OutputImage_val = powerTransform(app.InputImage_val, app.PT_cEditField.Value, app.PT_gEditField.Value);
            elseif strcmp ('Inverse Log Transform', method)
                app.OutputImage_val = invLogTransform(app.InputImage_val, app.ILT_cEditField.Value);
            elseif strcmp('Piecewise Linear Transform', method)
                app.OutputImage_val = piecewiseLinearTransform(app.InputImage_val, app.PLT_min.Value, app.PLT_max.Value);
            elseif strcmp('Histogram Equalization', method)
                app.OutputImage_val = histEqualize(app.InputImage_val);
            elseif strcmp('Histogram Specification', method)
                app.OutputImage_val = histSpecify(app.InputImage_val, app.SpecImage_val);
            end
            imshow(app.OutputImage_val, 'Parent', app.OutputImage);
            app.OutputImage.XLim = [0, size(app.OutputImage_val, 2)];
            app.OutputImage.YLim = [0, size(app.OutputImage_val, 1)];
            SetHistogram(app, app.OutputHistAxes, app.OutputImage_val);
        end

        % Value changed function: SpecInputFileEditField
        function SpecInputFileEditFieldValueChanged(app, event)
            value = app.SpecInputFileEditField.Value;
            app.SpecImage_2.ImageSource = value;
            app.SpecImage_val = imread(value);
            SetHistogram(app, app.SpecHistAxes_2, app.SpecImage_val);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputHistAxes = uiaxes(app.UIFigure);
            title(app.InputHistAxes, 'Input Image Histogram')
            xlabel(app.InputHistAxes, 'X')
            ylabel(app.InputHistAxes, 'Y')
            zlabel(app.InputHistAxes, 'Z')
            app.OutputHistAxes = uiaxes(app.UIFigure);
            title(app.OutputHistAxes, 'Output Image Histogram')
            xlabel(app.OutputHistAxes, 'X')
            ylabel(app.OutputHistAxes, 'Y')
            zlabel(app.OutputHistAxes, 'Z')
            app.OutputImage = uiaxes(app.UIFigure);
            app.OutputImage.XTick = [];
            app.OutputImage.YTick = [];
            app.OutputImage.ZTick = [];
            app.PanelTemplate = uipanel(app.UIFigure);
            app.PanelTemplate.Visible = 'off';
            app.HistSpecInputPanel = uipanel(app.UIFigure);
            app.HistSpecInputPanel.Visible = 'off';
            app.SpecHistAxes_2 = uiaxes(app.HistSpecInputPanel);
            title(app.SpecHistAxes_2, 'Specification Image Histogram')
            xlabel(app.SpecHistAxes_2, 'X')
            ylabel(app.SpecHistAxes_2, 'Y')
            zlabel(app.SpecHistAxes_2, 'Z')
            app.InputSpecificationImageLabel = uilabel(app.HistSpecInputPanel);
            app.InputSpecificationImageLabel.Text = 'Input Specification Image';
            app.SpecInputFileEditField = uieditfield(app.HistSpecInputPanel, 'text');
            app.SpecInputFileEditField.ValueChangedFcn = createCallbackFcn(app, @SpecInputFileEditFieldValueChanged, true);
            app.SpecImage_2 = uiimage(app.HistSpecInputPanel);
            app.ImageBrigthenInputPanel = uipanel(app.UIFigure);
            app.ImageBrigthenInputPanel.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.ImageBrigthenInputPanel);
            app.aEditFieldLabel.Text = 'a';
            app.Brighten_aEditField = uieditfield(app.ImageBrigthenInputPanel, 'numeric');
            app.Brighten_aEditField.Limits = [-255 255];
            app.Brighten_aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.ImageBrigthenInputPanel);
            app.bEditFieldLabel.Text = 'b';
            app.Brighten_bEditField = uieditfield(app.ImageBrigthenInputPanel, 'numeric');
            app.Brighten_bEditField.Limits = [-255 255];
            app.kakbLabel = uilabel(app.ImageBrigthenInputPanel);
            app.kakbLabel.Text = 'k'' = a * k + b';
            app.PowerTransformInputPanel = uipanel(app.UIFigure);
            app.PowerTransformInputPanel.Visible = 'off';
            app.gLabel = uilabel(app.PowerTransformInputPanel);
            app.gLabel.Text = 'g';
            app.PT_gEditField = uieditfield(app.PowerTransformInputPanel, 'numeric');
            app.PT_gEditField.Limits = [-255 255];
            app.PT_gEditField.Value = 1;
            app.cLabel = uilabel(app.PowerTransformInputPanel);
            app.cLabel.Text = 'c';
            app.PT_cEditField = uieditfield(app.PowerTransformInputPanel, 'numeric');
            app.PT_cEditField.Limits = [-255 255];
            app.PT_cEditField.Value = 1;
            app.kckgLabel = uilabel(app.PowerTransformInputPanel);
            app.kckgLabel.Text = 'k'' = c * k ^ g';
            app.InverseLogTransformInputPanel = uipanel(app.UIFigure);
            app.InverseLogTransformInputPanel.Visible = 'off';
            app.gLabel_2 = uilabel(app.InverseLogTransformInputPanel);
            app.gLabel_2.Text = 'g';
            app.ILT_cEditField = uieditfield(app.InverseLogTransformInputPanel, 'numeric');
            app.ILT_cEditField.Limits = [-255 255];
            app.ILT_cEditField.Value = 1;
            app.kexpkc1Label = uilabel(app.InverseLogTransformInputPanel);
            app.kexpkc1Label.Text = 'k'' = exp(k ^ c) - 1';
            app.PiecewiseLinearTransformInputPanel = uipanel(app.UIFigure);
            app.PiecewiseLinearTransformInputPanel.Visible = 'off';
            app.maxLabel = uilabel(app.PiecewiseLinearTransformInputPanel);
            app.maxLabel.Text = 'max';
            app.PLT_max = uieditfield(app.PiecewiseLinearTransformInputPanel, 'numeric');
            app.PLT_max.Limits = [-255 255];
            app.PLT_max.Value = 1;
            app.minLabel = uilabel(app.PiecewiseLinearTransformInputPanel);
            app.minLabel.Text = 'min';
            app.PLT_min = uieditfield(app.PiecewiseLinearTransformInputPanel, 'numeric');
            app.PLT_min.Limits = [-255 255];
            app.PLT_min.Value = 1;
            app.InputImage = uiimage(app.UIFigure);
            app.RedChannelCheckBox = uicheckbox(app.UIFigure);
            app.RedChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.RedChannelCheckBox.Text = 'Red Channel';
            app.RedChannelCheckBox.Value = true;
            app.GreenChannelCheckBox = uicheckbox(app.UIFigure);
            app.GreenChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.GreenChannelCheckBox.Text = 'Green Channel';
            app.GreenChannelCheckBox.Value = true;
            app.BlueChannelCheckBox = uicheckbox(app.UIFigure);
            app.BlueChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.BlueChannelCheckBox.Text = 'BlueChannel';
            app.BlueChannelCheckBox.Value = true;
            app.InputFileEditFieldLabel = uilabel(app.UIFigure);
            app.InputFileEditFieldLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.UIFigure, 'text');
            app.InputFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputFileEditFieldValueChanged, true);
            app.RestorationMethodDropDownLabel = uilabel(app.UIFigure);
            app.RestorationMethodDropDownLabel.Text = 'Restoration Method';
            app.RestorationMethodDropDown = uidropdown(app.UIFigure);
            app.RestorationMethodDropDown.Items = {'Image Brightening', 'Contrast Stretching', 'Piecewise Linear Transform', 'Power Transform', 'Inverse Log Transform', 'Histogram Equalization', 'Histogram Specification'};
            app.RestorationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @RestorationMethodDropDownValueChanged, true);
            app.RestorationMethodDropDown.Value = 'Histogram Equalization';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end